classdef image123 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FILTERTYPEDropDown        matlab.ui.control.DropDown
        FILTERTYPEDropDownLabel   matlab.ui.control.Label
        IMAGEPROCESSINGANDEDGEDETECTIONLabel  matlab.ui.control.Label
        THRESHOLDSlider           matlab.ui.control.Slider
        THRESHOLDSliderLabel      matlab.ui.control.Label
        KERNELSIZEDropDown        matlab.ui.control.DropDown
        KERNELSIZEDropDownLabel   matlab.ui.control.Label
        EDGEDETECTIONDropDown     matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        SAVEIMAGEButton           matlab.ui.control.Button
        RESETButton               matlab.ui.control.Button
        APPLYEDGEDETECTIONButton  matlab.ui.control.Button
        APPLYFILTERButton         matlab.ui.control.Button
        LOADIMAGEButton           matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage  % Original loaded image
        ProcessedImage % Processed image
        CurrentImage   % Current working image
        % Description
    end
    
    methods (Access = private)
        % ---------- FILTER KERNEL --------------------
        function kernel = getFilterKernel(app, filterType, kernelSize)
            switch filterType

                case 'Average (Low-Pass)'
                    kernel = ones(kernelSize) / (kernelSize^2);

                case 'Gaussian'
                    sigma = kernelSize/6;
                    kernel = fspecial('gaussian',[kernelSize kernelSize], sigma);

                case 'Sharpening'
                    if kernelSize == 3
                        kernel=[0 -1 0; -1 5 -1; 0 -1 0];
                    else
                        kernel=[-1 -1 -1; -1 9 -1; -1 -1 -1];
                    end

                case 'Laplacian'
                    kernel=fspecial('laplacian',0.2);

                case 'Motion Blur'
                    kernel = fspecial('motion',kernelSize,45);

                case 'Unsharp Mask'
                    kernel = fspecial('unsharp');

                case 'High-Pass'
                    kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];
            end
        end

        % ---------- APPLY CONVOLUTION --------------------
        function filtered = applyConvolution(app, img, kernel)
            if size(img,3)==3
                filtered=zeros(size(img));
                for i=1:3
                    filtered(:,:,i)=conv2(double(img(:,:,i)),kernel,'same');
                end
            else
                filtered = conv2(double(img),kernel,'same');
            end
            filtered = uint8(filtered);
        end

        % ---------- CUSTOM EDGE DETECTION --------------------
        function edges = detectEdgesConvolution(app, img, method, threshold)
            if size(img,3)==3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end

            grayImg = double(grayImg);

            switch method
                case 'Sobel'
                    Gx=[-1 0 1; -2 0 2; -1 0 1];
                    Gy=[-1 -2 -1; 0 0 0; 1 2 1];

                case 'Prewitt'
                    Gx=[-1 0 1; -1 0 1; -1 0 1];
                    Gy=[-1 -1 -1; 0 0 0; 1 1 1];

                case 'Roberts'
                    Gx=[1 0; 0 -1];
                    Gy=[0 1; -1 0];

                case 'Laplacian'
                    kernel=[0 1 0; 1 -4 1; 0 1 0];
                    edges=abs(conv2(grayImg,kernel,'same'));
                    edges = edges > threshold * max(edges(:));
                    edges=uint8(edges)*255;
                    return;

                case 'Canny'
                    edges = edge(uint8(grayImg),'canny',threshold);
                    edges = uint8(edges)*255;
                    return;

                case 'Laplacian of Gaussian'
                    h = fspecial('gaussian',[5 5],1);
                    smoothed = conv2(grayImg,h,'same');
                    kernel=[0 1 0; 1 -4 1; 0 1 0];
                    edges=abs(conv2(smoothed,kernel,'same'));
                    edges = edges > threshold * max(edges(:));
                    edges=uint8(edges)*255;
                    return;
            end

            Ex = conv2(grayImg,Gx,'same');
            Ey = conv2(grayImg,Gy,'same');
            magnitude = sqrt(Ex.^2 + Ey.^2);

            edges = magnitude > threshold * max(magnitude(:));
            edges = uint8(edges)*255;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADIMAGEButton
        function LOADIMAGEButtonPushed(app, event)
        [file,path] = uigetfile({'*.jpg;*.png;*.jpeg'});
            if isequal(file,0), return; end

            app.OriginalImage = imread(fullfile(path,file));
            imshow(app.OriginalImage,'Parent',app.UIAxes);
        end

        % Button pushed function: APPLYFILTERButton
        function APPLYFILTERButtonPushed(app, event)
       if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Load an image first!','Error');
                return;
            end

            img = rgb2gray(app.OriginalImage);
            k = str2double(app.KERNELSIZEDropDown.Value);

            switch app.FILTERTYPEDropDown.Value

                case 'Low Pass'
                    h = fspecial('average',k);
                    result = imfilter(img,h);

                case 'High Pass'
                    h = [-1 -1 -1; -1 8 -1; -1 -1 -1];
                    result = imfilter(img,h);

                case 'Gaussian Blur'
                    sigma = k/3;
                    h = fspecial('gaussian',k,sigma);
                    result = imfilter(img,h);

                case 'Median Filter'
                    result = medfilt2(img,[k k]);

                case 'Sharpen'
                    result = imsharpen(img,'Radius',k,'Amount',1.2);
            end

            app.ProcessedImage = result;
            imshow(result,'Parent',app.UIAxes2);
        
        end

        % Button pushed function: APPLYEDGEDETECTIONButton
        function APPLYEDGEDETECTIONButtonPushed(app, event)
         
    if isempty(app.OriginalImage)
        uialert(app.UIFigure,'Load an image first!','Error');
        return;
    end

    img = rgb2gray(app.OriginalImage);
    t = app.THRESHOLDSlider.Value;

    % Make threshold safe
    if t < 0 || t > 1
        t = 0.1;
    end

    switch app.EDGEDETECTIONDropDown.Value

        case 'Sobel'
            if t > 0.3
                t = 0.1;
            end
            edges = edge(img,'sobel',t);

        case 'Prewitt'
            if t > 0.3
                t = 0.1;
            end
            edges = edge(img,'prewitt',t);

        case 'Roberts'
            if t > 0.3
                t = 0.1;
            end
            edges = edge(img,'roberts',t);

        case 'Canny'
            low = t/2;
            high = t;
            if low <= 0, low = 0.05; end
            if high <= low, high = low + 0.05; end
            edges = edge(img,'canny',[low high]);

        case 'LoG'
            if t > 0.3
                t = 0.1;
            end
            edges = edge(img,'log',t);

    end

    app.ProcessedImage = edges;
    imshow(edges,'Parent',app.UIAxes2);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
           cla(app.UIAxes);
            cla(app.UIAxes2);
            app.OriginalImage = [];
            app.ProcessedImage = [];
        end

        % Button pushed function: SAVEIMAGEButton
        function SAVEIMAGEButtonPushed(app, event)
           if isempty(app.ProcessedImage)
                uialert(app.UIFigure,'No processed image to save!','Error');
                return;
            end

            [file,path] = uiputfile({'*.png';'*.jpg';'*.bmp'},'Save Image');

            if isequal(file,0)
                return;
            end

            imwrite(app.ProcessedImage, fullfile(path,file));

            uialert(app.UIFigure,'Image Saved Successfully!','Saved');
        end

        % Value changed function: THRESHOLDSlider
        function THRESHOLDSliderValueChanged(app, event)
         val = app.THRESHOLDSlider.Value;
            app.THRESHOLDSliderLabel.Text = sprintf('THRESHOLD: %.2f',val);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ORIGINAL IMAGE')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PROCESSED IMAGE')
            zlabel(app.UIAxes2, 'Z')
            app.LOADIMAGEButton = uibutton(app.UIFigure, 'push');
            app.LOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADIMAGEButtonPushed, true);
            app.LOADIMAGEButton.Text = 'LOAD IMAGE';
            app.APPLYFILTERButton = uibutton(app.UIFigure, 'push');
            app.APPLYFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYFILTERButtonPushed, true);
            app.APPLYFILTERButton.Text = 'APPLY FILTER';
            app.APPLYEDGEDETECTIONButton = uibutton(app.UIFigure, 'push');
            app.APPLYEDGEDETECTIONButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYEDGEDETECTIONButtonPushed, true);
            app.APPLYEDGEDETECTIONButton.Text = 'APPLY EDGE DETECTION';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SAVEIMAGEButton = uibutton(app.UIFigure, 'push');
            app.SAVEIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEIMAGEButtonPushed, true);
            app.SAVEIMAGEButton.Text = 'SAVE IMAGE';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'EDGE DETECTION';
            app.EDGEDETECTIONDropDown = uidropdown(app.UIFigure);
            app.EDGEDETECTIONDropDown.Items = {'Sobel', 'Prewitt', 'Roberts', 'Canny', 'LoG'};
            app.EDGEDETECTIONDropDown.Value = 'Sobel';
            app.KERNELSIZEDropDownLabel = uilabel(app.UIFigure);
            app.KERNELSIZEDropDownLabel.Text = 'KERNEL SIZE';
            app.KERNELSIZEDropDown = uidropdown(app.UIFigure);
            app.KERNELSIZEDropDown.Items = {'3', '5', '7', '9', '11'};
            app.KERNELSIZEDropDown.Value = '3';
            app.THRESHOLDSliderLabel = uilabel(app.UIFigure);
            app.THRESHOLDSliderLabel.Text = 'THRESHOLD';
            app.THRESHOLDSlider = uislider(app.UIFigure);
            app.THRESHOLDSlider.ValueChangedFcn = createCallbackFcn(app, @THRESHOLDSliderValueChanged, true);
            app.IMAGEPROCESSINGANDEDGEDETECTIONLabel = uilabel(app.UIFigure);
            app.IMAGEPROCESSINGANDEDGEDETECTIONLabel.Text = 'IMAGE PROCESSING AND EDGE DETECTION';
            app.FILTERTYPEDropDownLabel = uilabel(app.UIFigure);
            app.FILTERTYPEDropDownLabel.Text = 'FILTER TYPE';
            app.FILTERTYPEDropDown = uidropdown(app.UIFigure);
            app.FILTERTYPEDropDown.Items = {'Low Pass', 'High Pass', 'Gaussian Blur', 'Median Filter', 'Sharpen'};
            app.FILTERTYPEDropDown.Value = 'Low Pass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image123

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end